classdef ExpertView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OriginalImgView             matlab.ui.control.UIAxes
        ExpertImgView               matlab.ui.control.UIAxes
        NextImgButton               matlab.ui.control.Button
        SelectOverEstimatedRegion   matlab.ui.control.Button
        SelectUnderEstimatedRegion  matlab.ui.control.Button
        PreImgButton                matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        PersonInfo                  matlab.ui.control.Label
        PersonLabel                 matlab.ui.control.Label
        PersonNumber                matlab.ui.control.NumericEditField
        SliceLabel                  matlab.ui.control.Label
        SliceNumber                 matlab.ui.control.NumericEditField
        GoButton                    matlab.ui.control.Button
        ResetOverEstimatedforThisSliceButton  matlab.ui.control.Button
        ResetUnderEstimatedforThisSliceButton  matlab.ui.control.Button
    end

    
    properties (Access = private)

        Current_Person=1;
        Current_Slice=0;
        Source;
        SourceFilePath;
        Target;
        TargetFilePath;
    end
    
    methods (Access = private)
        
        function  SaveTarget(app)            
            Target=app.Target;
            save(app.TargetFilePath,'Target');
        end
        
        function LoadVariable(app)            
            temp= load(app.SourceFilePath);
                       
            app.Source = temp.Source;            
            temp = load(app.TargetFilePath);
            app.Target=temp.Target;
            disableDefaultInteractivity(app.OriginalImgView);
            disableDefaultInteractivity(app.ExpertImgView);
        end
        
        function ShowSourceImages(app)
             app.PersonInfo.Text=[num2str(app.Current_Person), ' person ' , num2str(app.Current_Slice) , ' slice '];
            
            try
                imshow(app.Source.Original{app.Current_Person}(:,:,app.Current_Slice),[],'Parent',app.OriginalImgView);
                imshow(app.Source.MyWork{app.Current_Person}(:,:,app.Current_Slice),[],'Parent',app.ExpertImgView);
            catch
                msgbox('The matrix limit is exceeded');
            end
        end
        
        function TargetImg = SelectFailAreas(app,SourceImg,TargetImg)
            figure;
            subplot 121;
            imshow(TargetImg,[]);
            subplot 122;
            imshow(SourceImg,[]);
            set(gcf, 'Position', get(0, 'Screensize'));
            h_rect = drawrectangle();
            try
                pos_rect = round( h_rect.Position);
                for x=pos_rect(2):pos_rect(2)+pos_rect(4)
                    for y=pos_rect(1):pos_rect(1)+pos_rect(3)
                        if SourceImg(x,y)>0
                            TargetImg(x,y) = SourceImg(x,y);
                        end
                    end
                end
            catch
            end
            
            close;
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: NextImgButton
        function NextImgButtonButtonPushed(app, event)
            if isempty(app.Source) || isempty(app.Target)
                msgbox('Please load Source and Target files.');
                return;
            end
            if app.Current_Person ==19 && app.Current_Slice==21
            else
                app.Current_Slice = app.Current_Slice+1;
                [row, col, slice] = size(app.Source.Original{app.Current_Person});
                if app.Current_Slice > slice
                    app.Current_Person = app.Current_Person +1;
                    app.Current_Slice = 1;
                end
                ShowSourceImages(app);
            end
        end

        % Button pushed function: SelectUnderEstimatedRegion
        function SelectUnderEstimatedRegionButtonPushed(app, event)
            
            if isempty(app.Current_Person==1 && app.Current_Slice==0)
                msgbox('Please open an image.');
                return;
            end
            if isnan(app.Target.UnderEstimate{app.Current_Person}(1,1,app.Current_Slice))
                [row, col, slice]=size(app.Target.UnderEstimate{app.Current_Person});
                app.Target.UnderEstimate{app.Current_Person}(:,:,app.Current_Slice)=zeros(row,col);
            end
          
            TargetImg = app.Target.UnderEstimate{app.Current_Person}(:,:,app.Current_Slice);
            while true
                TargetImg = SelectFailAreas(app,app.OriginalImgView.Children.CData,TargetImg);
                if TargetImg == app.Target.UnderEstimate{app.Current_Person}(:,:,app.Current_Slice)
                    break;
                else
                    app.Target.UnderEstimate{app.Current_Person}(:,:,app.Current_Slice)= TargetImg;                    
            SaveTarget(app);
                end
            end
        end

        % Button pushed function: PreImgButton
        function PreImgButtonButtonPushed(app, event)
            if isempty(app.Source) || isempty(app.Target)
                msgbox('Please load Source and Target files.');
                return;
            end
            if app.Current_Person ==1 && app.Current_Slice==1
            elseif app.Current_Person ==1 && app.Current_Slice==0
            else
                app.Current_Slice = app.Current_Slice-1;
                [row, col, slice] = size(app.Source.Original{app.Current_Person});
                if app.Current_Slice < 1
                    app.Current_Person = app.Current_Person -1;
                    [row, col, slice] = size(app.Source.Original{app.Current_Person});
                    app.Current_Slice = slice;
                end
               ShowSourceImages(app);
            end
        end

        % Button pushed function: SelectOverEstimatedRegion
        function SelectOverEstimatedRegionButtonPushed(app, event)
            if isempty(app.Current_Person==1 && app.Current_Slice==0)
                msgbox('Please open an image.');
                return;
            end
            if isnan(app.Target.OverEstimate{app.Current_Person}(1,1,app.Current_Slice))
                [row, col, slice]=size(app.Target.OverEstimate{app.Current_Person});
                app.Target.OverEstimate{app.Current_Person}(:,:,app.Current_Slice)=zeros(row,col);
            end
            TargetImg = app.Target.OverEstimate{app.Current_Person}(:,:,app.Current_Slice);
            while true
                TargetImg = SelectFailAreas(app,app.ExpertImgView.Children.CData,TargetImg);
                if TargetImg == app.Target.OverEstimate{app.Current_Person}(:,:,app.Current_Slice)
                    msgbox('There is no change');
                    break;
                else
                    app.Target.OverEstimate{app.Current_Person}(:,:,app.Current_Slice)= TargetImg;                    
                    
            SaveTarget(app);
                end
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonButtonPushed(app, event)
            
            [file,path] = uigetfile('','Select Source file');
            app.SourceFilePath=[path,file];
            
            [file,path] = uigetfile('','Select Target file');
            app.TargetFilePath=[path,file];
            LoadVariable(app);
           
        end

        % Callback function
        function SaveTargetButtonPushed(app, event)
            SaveTarget(app);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if isempty(app.Source) || isempty(app.Target)
                msgbox('Please load Source and Target files.');
                return;
            end
            
            app.Current_Person = app.PersonNumber.Value;
            app.Current_Slice = app.SliceNumber.Value;
            
            
            app.PersonInfo.Text=[num2str(app.Current_Person), 'person ' , num2str(app.Current_Slice) , 'slice '];
            
            try
                imshow(app.Source.Original{app.Current_Person}(:,:,app.Current_Slice),[],'Parent',app.OriginalImgView);
                imshow(app.Source.MyWork{app.Current_Person}(:,:,app.Current_Slice),[],'Parent',app.ExpertImgView);
            catch
                msgbox('The matrix limit is exceeded');
            end
               
        end

        % Button pushed function: ResetOverEstimatedforThisSliceButton
        function ResetOverEstimatedforThisSliceButtonPushed(app, event)
            app.Target.OverEstimate{app.Current_Person}(:,:,app.Current_Slice)=zeros(size(app.Target.OverEstimate{app.Current_Person}(:,:,app.Current_Slice))); %seval
            SaveTarget(app); %seval
        end

        % Button pushed function: 
        % ResetUnderEstimatedforThisSliceButton
        function ResetUnderEstimatedforThisSliceButtonPushed(app, event)
            %seval
            app.Target.UnderEstimate{app.Current_Person}(:,:,app.Current_Slice)=zeros(size(app.Target.UnderEstimate{app.Current_Person}(:,:,app.Current_Slice)));
            SaveTarget(app); %seval
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OriginalImgView = uiaxes(app.UIFigure);
            title(app.OriginalImgView, 'Original Image')
            xlabel(app.OriginalImgView, 'X')
            ylabel(app.OriginalImgView, 'Y')
            app.ExpertImgView = uiaxes(app.UIFigure);
            title(app.ExpertImgView, 'Manual Marking')
            xlabel(app.ExpertImgView, 'X')
            ylabel(app.ExpertImgView, 'Y')
            app.NextImgButton = uibutton(app.UIFigure, 'push');
            app.NextImgButton.ButtonPushedFcn = createCallbackFcn(app, @NextImgButtonButtonPushed, true);
            app.NextImgButton.Text = 'Next Image';
            app.SelectOverEstimatedRegion = uibutton(app.UIFigure, 'push');
            app.SelectOverEstimatedRegion.ButtonPushedFcn = createCallbackFcn(app, @SelectOverEstimatedRegionButtonPushed, true);
            app.SelectOverEstimatedRegion.Text = 'Select OverEstimated';
            app.SelectUnderEstimatedRegion = uibutton(app.UIFigure, 'push');
            app.SelectUnderEstimatedRegion.ButtonPushedFcn = createCallbackFcn(app, @SelectUnderEstimatedRegionButtonPushed, true);
            app.SelectUnderEstimatedRegion.Text = 'Select UnderEstimated';
            app.PreImgButton = uibutton(app.UIFigure, 'push');
            app.PreImgButton.ButtonPushedFcn = createCallbackFcn(app, @PreImgButtonButtonPushed, true);
            app.PreImgButton.Text = 'Previous Image';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonButtonPushed, true);
            app.LoadButton.Text = 'Load Source and Target';
            app.PersonInfo = uilabel(app.UIFigure);
            app.PersonInfo.Text = '';
            app.PersonLabel = uilabel(app.UIFigure);
            app.PersonLabel.Text = 'Person';
            app.PersonNumber = uieditfield(app.UIFigure, 'numeric');
            app.SliceLabel = uilabel(app.UIFigure);
            app.SliceLabel.Text = 'Slice';
            app.SliceNumber = uieditfield(app.UIFigure, 'numeric');
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ResetOverEstimatedforThisSliceButton = uibutton(app.UIFigure, 'push');
            app.ResetOverEstimatedforThisSliceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOverEstimatedforThisSliceButtonPushed, true);
            app.ResetOverEstimatedforThisSliceButton.Text = {'Reset OverEstimated '; 'for This Slice'};
            app.ResetUnderEstimatedforThisSliceButton = uibutton(app.UIFigure, 'push');
            app.ResetUnderEstimatedforThisSliceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetUnderEstimatedforThisSliceButtonPushed, true);
            app.ResetUnderEstimatedforThisSliceButton.Text = {'Reset UnderEstimated '; 'for This Slice'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = ExpertView

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end